classdef CovidApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KellersCovidappUIFigure    matlab.ui.Figure
        CountryListBox             matlab.ui.control.ListBox
        CountryLabel               matlab.ui.control.Label
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        AveragedofdaysSlider       matlab.ui.control.Slider
        AveragedofdaysLabel        matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Globe
        Countries
        States
        Timepoints
        PlottedCases
        PlottedDeaths
        PlottedPlace
        CountryIndex
        StateIndex
        DataIndex
        DailyLogi
        CumulativeLogi
        DailyVCumulative
        CumulativeData
        Slider
    end
    
    methods (Access = private)
        
        function plotData(app)
            % Clear figure
            cla(app.UIAxes, "reset");
            % Setting axis settings
            app.UIAxes.XLim = [app.Timepoints(1) app.Timepoints(end)];
            for idx = 1:height(app.UIAxes.XTickLabel)
                app.UIAxes.XTickLabel{idx} =  datestr(str2num(app.UIAxes.XTickLabel{idx}),'mmm yy');
            end
            app.UIAxes.XLabel.String = [];
            app.UIAxes.YLabel.String = [];
            % Calculating points
            app.points2plot();
            switch app.DataIndex
                case 3 
                    yyaxis(app.UIAxes,'left')
                    bar(app.UIAxes,app.Timepoints,app.PlottedCases);
                    hold(app.UIAxes, 'on')
                    yyaxis(app.UIAxes,'right')
                    plot(app.UIAxes,app.Timepoints,app.PlottedDeaths,'Color','#D95319');
                    legend(app.UIAxes, 'Cases','Deaths','location','northwest');
                    title(app.UIAxes,[app.DailyVCumulative ' Number of Cases and Deaths in ' app.PlottedPlace ' (',num2str(round(app.AveragedofdaysSlider.Value)),' day mean)'])
                    hold(app.UIAxes, 'off')
                case 2
                    hold(app.UIAxes, 'off')
                    plot(app.UIAxes,app.Timepoints,app.PlottedDeaths,'Color','#D95319');
                    title(app.UIAxes,[app.DailyVCumulative ' Number of Deaths in ' app.PlottedPlace ' (',num2str(round(app.AveragedofdaysSlider.Value)),' day mean)'])
                    hold(app.UIAxes, 'off')
                otherwise
                    hold(app.UIAxes, 'off')
                    bar(app.UIAxes,app.Timepoints,app.PlottedCases);
                    title(app.UIAxes,[app.DailyVCumulative ' Number of Cases in ' app.PlottedPlace ' (',num2str(round(app.AveragedofdaysSlider.Value)),' day mean)'])
                    hold(app.UIAxes, 'off')
            end
        end

        function points2plot(app)
            % initialize arrays 
            Cases = zeros(1,length(app.Timepoints));
            Deaths = zeros(1,length(app.Timepoints));
            % fill arrays 
            for idx = 2:length(app.Timepoints)+1
                Cases(idx-1) = app.States{app.CountryIndex}{app.StateIndex,idx}(1);
                Deaths(idx-1) = app.States{app.CountryIndex}{app.StateIndex,idx}(2);
            end            
            if app.DailyLogi
                app.PlottedDeaths = movmean([0 diff(Deaths)],app.Slider);              
                app.PlottedCases = movmean([0 diff(Cases)],app.Slider);
                app.PlottedCases(app.PlottedCases < 0) = 0;
                app.PlottedDeaths(app.PlottedDeaths < 0) = 0;
                app.DailyVCumulative = 'Daily';
            end
            if app.CumulativeLogi
                app.PlottedDeaths = movmean(Deaths,app.Slider);              
                app.PlottedCases = movmean(Cases,app.Slider);
                app.DailyVCumulative = 'Cumulative';
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc   
            % Load data in
            load covid_data.mat covid_data
            [r, ~] = size(covid_data);
            Time = covid_data(1,3:end);
            formatIn = 'mm/dd/yyyy';
            app.Timepoints = datenum(Time,formatIn);
            
            %Making 'All'
            for idx = 1 : r
                if isempty(covid_data{idx,2})
                  covid_data{idx,2} = 'All';
                end
            end
            
            %setting up world
            covid_data(1,:) = [];
            countries = unique(covid_data(:,1));
            countries = ['World'; countries];
            cases = 0;
            deaths = 0;
            for idx = 3:width(covid_data)
                for idx2 = 2:height(covid_data)
                    cases = cases + covid_data{idx2,idx}(1);
                    deaths = deaths + covid_data{idx2,idx}(2);
                end
                M1World{1,idx-1} = [cases,deaths];
            end
            M1World{1,1} = 'All';
            M1 = splitapply(@(x) {x}, covid_data(:,2:end), findgroups(covid_data(:,1)));
            cell{1,1} = M1World;
            M1 = [cell; M1];
           
            
            app.Globe = ctnr("Global");
            app.Globe.regions = ctnr.empty(numel(countries),0);
            app.Globe.data = covid_data(:,2:end);
            
            for idx = 1 : numel(countries)
                app.Globe.regions(idx) = countries(idx);
                app.Globe.regions(idx).regions = ctnr.empty(size(M1{idx},1),0);
                for idx2 = 1 : size(M1{idx},1)
                    app.Globe.regions(idx).regions(idx2) = M1{idx}{idx2};
                    app.Globe.regions(idx).regions(idx2).data = M1{idx}(idx2,2:end);
                end
            end 
            
            % Set Properties
            app.Countries = countries;
            app.States = M1;
            
            % Set Default settings - CountryListBox
            app.CountryListBox.Items = app.Countries;
            app.CountryListBox.ItemsData=[];
            app.CountryListBox.Value = 'World';
            app.PlottedPlace ='World';
            app.CountryIndex = 1;

            % Set Default settings - StateListBox
            app.StateIndex = 1;
            app.StateorRegionListBox.Items = M1{app.CountryIndex}(app.StateIndex);
            app.StateorRegionListBox.Value = 'All';            

            % Set Default Settings - Plot
            points = [];
            for idx = 2:width(M1{1})
                points = [points, M1{1}{idx}(1)];
            end
            app.PlottedCases = points;
            app.CumulativeLogi = 1;
            app.DataIndex = 1;
            app.Slider = 1;
            app.plotData();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
        %Get Value
        value = app.CountryListBox.Value;
        % Index out states
        app.CountryIndex = find(contains(app.Countries,value));
        app.StateIndex = 1;
        app.StateorRegionListBox.Items = app.States{app.CountryIndex}(:,1);
        app.PlottedPlace = app.CountryListBox.Value;
        app.plotData();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
        % Get value
        value = app.StateorRegionListBox.Value;

        % Get Index
        app.StateIndex = find(contains(app.StateorRegionListBox.Items,value));
        if strcmp(value,'All')
            app.PlottedPlace = app.CountryListBox.Value;
        else
            app.PlottedPlace = app.StateorRegionListBox.Value;
        end
        app.plotData();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatatoPlotButtonGroup.SelectedObject;
            if app.CasesButton.Value
                app.DataIndex = 1;
                app.plotData();
            elseif app.DeathsButton.Value
                app.DataIndex = 2;
                app.plotData();
            else
                app.DataIndex = 3;
                app.plotData();
            end
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.DailyLogi = app.DailyButton.Value;
            app.CumulativeLogi = app.CumulativeButton.Value;
            app.plotData();
        end

        % Callback function
        function AveragedofdaysSliderValueChanging(app, event)

        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            app.Slider = round(event.Value);
            app.plotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KellersCovidappUIFigure = uifigure('Visible', 'off');
            app.KellersCovidappUIFigure.Name = 'Keller''s Covid app';
            app.UIAxes = uiaxes(app.KellersCovidappUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StateorRegionListBoxLabel = uilabel(app.KellersCovidappUIFigure);
            app.StateorRegionListBoxLabel.Text = {'State or '; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.KellersCovidappUIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.AveragedofdaysLabel = uilabel(app.KellersCovidappUIFigure);
            app.AveragedofdaysLabel.Text = {'Averaged #'; ' of days'};
            app.AveragedofdaysSlider = uislider(app.KellersCovidappUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.KellersCovidappUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.KellersCovidappUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.CountryLabel = uilabel(app.KellersCovidappUIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.KellersCovidappUIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            % Show the figure after all components are created
            app.KellersCovidappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KellersCovidappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KellersCovidappUIFigure)
        end
    end
end